classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        BtnCW        matlab.ui.control.StateButton
        BtnCCW       matlab.ui.control.StateButton
        BtnCamera    matlab.ui.control.Button
        BtnStream    matlab.ui.control.Button
        BtnBwdLeft   matlab.ui.control.StateButton
        BtnFwdLeft   matlab.ui.control.StateButton
        BtnBwdRight  matlab.ui.control.StateButton
        BtnFwdRight  matlab.ui.control.StateButton
        BtnLeft      matlab.ui.control.StateButton
        BtnBackward  matlab.ui.control.StateButton
        BtnRight     matlab.ui.control.StateButton
        BtnForward   matlab.ui.control.StateButton
        SldSpeed     matlab.ui.control.Slider
        SPEEDLabel   matlab.ui.control.Label
        BtnSimulate  matlab.ui.control.Button
        BtnConnect   matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    properties(Constant)
        %DH Parameters [theta, d, a, alpha], these parameters have been
        %extracted from CoppeliaSim module.
        DH_params = [
            0, 0.1012, 0.0330, 90;    % Joint 0 -> Joint 1
            0, 0.0000, 0.1550, 0;     % Joint 1 -> Joint 2
            0, 0.0000, 0.1348, 0;     % Joint 2 -> Joint 3
            0, 0.0190, 0.0005, -90;   % Joint 3 -> Joint 4
            0, 0.0973, 0.0001, -90    % Joint 4 -> End-effector
            ];

    end
    properties (Access = private)
        %Constants
        structJointTypes = struct( ...
        'REVOLUTE', 1, ...
        'PRISMATIC', 2 ...
    );


        connection % Instance of RobotConnection
        joints %Array of Joint objects
        baseControl %Base control object
        visionSensor %Vision Sensor object
        wheelsSpeed = 1.0; 
        timer_sendPos %clock to call api for joint positions

        slamTimer
        laserScanner % Instance of LaserScanner class
        robotPose % Instance of RobotPoseEstimation class
        slamAlg % LidarSLAM object

        slamFigure % Figure for displaying the SLAM map
        LaserData % Add a property to store laser scan data

        loggedPoses % Array to store robot poses

    end
    
    methods (Access = private)
        
        function retrieveAndInitializeJoints(app)
                        
            % Retrieve wheel handles from CoppeliaSim (use correct object names)
            [handleWheelFR, ~] = app.connection.getHandle('rollingJoint_fr');
            [handleWheelFL, ~] = app.connection.getHandle('rollingJoint_fl');
            [handleWheelRR, ~] = app.connection.getHandle('rollingJoint_rr');
            [handleWheelRL, ~] = app.connection.getHandle('rollingJoint_rl');
            % Store the handles in an array to pass to BaseControl
            wheelHandles = [handleWheelFR, handleWheelFL, handleWheelRR, handleWheelRL];
            % Initialize the BaseControl object
            app.baseControl = BaseControl(wheelHandles);
           
            % %Initialize the VisionSensor
            [visionHandle, ~] = app.connection.getHandle('/youBot/rgb');
             app.visionSensor = VisionSensor(visionHandle);
              
            [baseHandle, resBase] = app.connection.getHandle('/youBot');
            app.robotPose = RobotPoseEstimation(app.connection.sim, app.connection.clientID, baseHandle);

            [laserHandle, resLaser] = app.connection.getHandle('/youBot/Hokuyo/laser');    
            app.laserScanner = LaserScanner(app.connection.sim, app.connection.clientID, laserHandle);
                
            maxRange = 5; % In CoppeliaSim, adjust as needed
            mapResolution = 20; % Resolution for the SLAM map (cells per meter)
            maxNumScans = 360; % Maximum number of scans
            app.slamAlg = lidarSLAM(mapResolution, maxRange, maxNumScans); % SLAM initialization
            
             % Start laser data streaming here
            [returnCode, ~] = app.connection.sim.simxGetStringSignal(app.connection.clientID, 'laserData', app.connection.sim.simx_opmode_streaming);
            if returnCode == app.connection.sim.simx_return_ok || returnCode == app.connection.sim.simx_return_novalue_flag
                disp('Laser data streaming initialized successfully.');
            else
                disp('Failed to initialize laser data streaming.');
            end
        end


        function disableUIControls(app)
           
            % Disable speed slider
            app.SldSpeed.Enable = 'off';

            % Disable base control buttons
            app.BtnFwdLeft.Enable = 'off';
            app.BtnBwdLeft.Enable = 'off';
            app.BtnFwdRight.Enable = 'off';
            app.BtnBwdRight.Enable = 'off';
            app.BtnForward.Enable = 'off';
            app.BtnBackward.Enable = 'off';
            app.BtnLeft.Enable = 'off';
            app.BtnRight.Enable = 'off';
            app.BtnCW.Enable = "off";
            app.BtnCCW.Enable = "off";
            app.BtnSimulate.Enable = "off";

            % Disable camera buttons
            app.BtnStream.Enable = 'off';
            app.BtnCamera.Enable = 'off';
                       
        end

        function enableUIControls(app)
            
            % Enable speed slider
            app.SldSpeed.Enable = 'on';

            % Enable base control buttons
            app.BtnFwdLeft.Enable = 'on';
            app.BtnBwdLeft.Enable = 'on';
            app.BtnFwdRight.Enable = 'on';
            app.BtnBwdRight.Enable = 'on';
            app.BtnForward.Enable = 'on';
            app.BtnBackward.Enable = 'on';
            app.BtnLeft.Enable = 'on';
            app.BtnRight.Enable = 'on';
            app.BtnCW.Enable = "on";
            app.BtnCCW.Enable = "on";
            app.BtnSimulate.Enable = "on";

            % Enable camera buttons
            app.BtnStream.Enable = 'on';
            app.BtnCamera.Enable = 'on';
        end

        function setupSliders(app)
            %Set limits for the speed slider 
            app.SldSpeed.Limits = [1,100];
        end


        function updateSLAM(app)
            % Retrieve laser data and robot pose
            try
                [res, data] = app.connection.sim.simxGetStringSignal(app.connection.clientID, 'laserData', app.connection.sim.simx_opmode_buffer);
                
                if res == app.connection.sim.simx_return_ok
                    % Decode the data (assuming format: x, y, z, theta, x, y, z, theta, ...)
                    app.LaserData = app.decode(data); 
                    
                    % Reshape the LaserData into a 4-column matrix: [x, y, z, theta]
                    reshapedData = reshape(app.LaserData, 4, [])';
                    x = reshapedData(:, 1);   % Extract x values
                    y = reshapedData(:, 2);   % Extract y values
                    z = reshapedData(:, 3);   % Extract z values (not used for r calculation)
                    theta = reshapedData(:, 4); % Extract theta values (in radians)
        
                    % Restrict theta to the range [-120°, 120°] or [-2*pi/3, 2*pi/3] radians
                    validThetaRange = [-2*pi/3, 2*pi/3]; % In radians
                    validThetaIndices = theta >= validThetaRange(1) & theta <= validThetaRange(2);
                    
                    % Apply the restriction to the theta and associated x, y, z values
                    theta = theta(validThetaIndices);
                    x = x(validThetaIndices);
                    y = y(validThetaIndices);
        
                    % Calculate r using Pythagorean theorem (ignoring z in the distance)
                    r = sqrt(x.^2 + y.^2);  % r = sqrt(x^2 + y^2)
        
                    % Filter out invalid laser readings (too close or too far)
                    minRange = 0.1;
                    maxRange = 5;
                    validIndices = r > minRange & r < maxRange;
                    r(~validIndices) = NaN; % Set invalid ranges to NaN
                    
                    % Convert the polar data (r, theta) to Cartesian coordinates
                    validCartesianIndices = ~isnan(r);
                    x_cartesian = r(validCartesianIndices) .* cos(theta(validCartesianIndices));  % x = r * cos(theta)
                    y_cartesian = r(validCartesianIndices) .* sin(theta(validCartesianIndices));  % y = r * sin(theta)
        
                    % Format the values to 2 decimal places
                    x_cartesian = round(x_cartesian, 2);
                    y_cartesian = round(y_cartesian, 2);
                    [filteredX, filteredY] = filterLaserData(app, x_cartesian, y_cartesian, theta);

                    if ~isempty(filteredX) && ~isempty(filteredY)
                        cartesianData = [filteredX, filteredY];
                        disp(cartesianData)
                        lidarScanObject = lidarScan(cartesianData); % Create lidar scan object with filtered data
                        robotPose = app.robotPose.getPose(); % Get current robot pose
                        addScan(app.slamAlg, lidarScanObject, robotPose); % Add to SLAM algorithm
                    else
                        disp('No valid laser data after filtering.');
                    end
                    logRobotPose(app);
                    
                else
                    disp('Failed to retrieve laser data.');
                end
            catch ME
                disp(['SLAM update error: ', ME.message]);
            end
        
            % Update SLAM map display
            try
                DisplaySLAMMap(app); % Assuming DisplaySLAMMap is a method to update the map
            catch ME
                disp(['Error displaying SLAM map: ', ME.message]);
            end
        end


        % Method to decode laser data
        function laserData = decode(~, data)
            % Convert the raw string data from CoppeliaSim into usable laser distance data.
            % Assuming data is a char array (string data from CoppeliaSim)
        
            % Convert the string to uint8 (bytes)
            rawBytes = uint8(data);
            
            % Typecast the bytes to single precision floats
            laserData = typecast(rawBytes, 'single');
        end


        function DisplaySLAMMap(app)
            % Assuming you have a figure to display the SLAM map
            if isempty(app.slamFigure) || ~isvalid(app.slamFigure)
                app.slamFigure = figure;
            end
            show(app.slamAlg);
        end

       function logRobotPose(app)
            currentPose = app.robotPose.getPose();  % Retrieve the robot's current pose
            
            % Round each component to two decimal places
            currentPose = round(currentPose, 2);
            
            % Log the rounded pose
            app.loggedPoses = [app.loggedPoses; currentPose];  % Append current pose to the log 
        end


        % Laser Data Filtering Function
        function [filteredX, filteredY] = filterLaserData(app, x, y, theta)
            % Define thresholds
            minRange = 0.1;   % Minimum valid distance
            maxRange = 5.0;   % Maximum valid distance
            validThetaRange = [-2*pi/3, 2*pi/3]; % Laser scan should be within 240 degrees
        
            % Calculate distance (range) from x, y coordinates
            r = sqrt(x.^2 + y.^2);
            
            % Apply filtering: valid range and theta
            validIndices = (r > minRange & r < maxRange) & (theta >= validThetaRange(1) & theta <= validThetaRange(2));
        
            % Apply filters to x, y coordinates
            filteredX = x(validIndices);
            filteredY = y(validIndices);
        end


   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize RobotConnection
            app.connection = RobotConnection();
            app.BtnConnect.Text = 'Connect';
            app.BtnConnect.Icon = 'icons/Connect.png'; 
        end

        % Button pushed function: BtnConnect
        function BtnConnectButtonPushed(app, event)
            % Check the current state of the connection
            if app.connection.clientID == -1
                success = app.connection.connect();
                if success
                    disp('Connected to CoppeliaSim.');
                    app.retrieveAndInitializeJoints();
                    app.setupSliders();
                    app.disableUIControls();
                    app.BtnConnect.Text = 'Disconnect';
                    app.BtnConnect.Icon = 'icons/Disconnect.png';
                    app.BtnSimulate.Enable = "on";
                    app.BtnSimulate.Icon = "icons/Start.png";
                else
                    disp('Failed to connect to CoppeliaSim.');
                end
            else
                app.connection.disconnect();
                app.disableUIControls();
                app.BtnConnect.Text = 'Connect';
                app.BtnConnect.Icon = 'icons/Connect.png';
            end
        end

        % Button pushed function: BtnSimulate
        function BtnSimulateButtonPushed(app, event)
            % Check if the client is connected
            if app.connection.clientID > -1
                if ~app.connection.isSimulationRunning
                    for i = 1:length(app.joints)
                        app.joints{i}.getCurrentPosition(app.connection.sim, app.connection.clientID);
                        app.joints{i}.setPosition(app.connection.sim, app.connection.clientID, app.joints{i}.currentPos);
                    end

                    app.connection.startSimulation();

                    if app.connection.isSimulationRunning
                        app.enableUIControls();
                        app.BtnSimulate.Icon = 'icons/Stop.png';

                        app.slamTimer = timer('ExecutionMode', 'fixedRate', ...
                            'Period', 1, ...
                            'TimerFcn', @(~, ~) updateSLAM(app));
                        start(app.slamTimer);
                    end
                else
                    app.connection.stopSimulation();

                    if ~app.connection.isSimulationRunning
                        app.disableUIControls();
                        app.BtnSimulate.Icon = 'icons/Start.png';
                        app.BtnSimulate.Enable = "on";
                        stop(app.slamTimer);
                        delete(app.slamTimer);
                        disp('Simulation stopped.');
                    end
                end
            else
                disp('Please connect to CoppeliaSim before starting the simulation.');
            end
        end

        % Value changed function: SldSpeed
        function SldSpeedValueChanged(app, event)
            newSpeedValue  = app.SldSpeed.Value;
            % Delegate the speed setting to each joint
            for i = 1:length(app.joints)
                app.joints{i}.setVelocity(app.connection.sim, app.connection.clientID, newSpeedValue);
            end
            %Delegate the speed setting to base control
           % base_allSpeed = app.baseControl.allSpeed;
           %app.baseControl.allSpeed = (newSpeedValue * 50.0) / 100;
           app.wheelsSpeed = (newSpeedValue * 5.0) / 100;
        end

        % Value changed function: BtnForward
        function BtnForwardValueChanged(app, event)
            value = app.BtnForward.Value;
            if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.FORWARD,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnBackward
        function BtnBackwardValueChanged(app, event)
            value = app.BtnBackward.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.BACKWARD,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnLeft
        function BtnLeftValueChanged(app, event)
            value = app.BtnLeft.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.LEFT,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnRight
        function BtnRightValueChanged(app, event)
            value = app.BtnRight.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.RIGHT,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnCW
        function BtnCWValueChanged(app, event)
            value = app.BtnCW.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.CLOCKWISE,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnCCW
        function BtnCCWValueChanged(app, event)
            value = app.BtnCCW.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.COUNTER_CLOCKWISE,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnFwdLeft
        function BtnFwdLeftValueChanged(app, event)
            value = app.BtnFwdLeft.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_FL,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnFwdRight
        function BtnFwdRightValueChanged(app, event)
            value = app.BtnFwdRight.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_FR,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnBwdLeft
        function BtnBwdLeftValueChanged(app, event)
            value = app.BtnBwdLeft.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_BL,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Value changed function: BtnBwdRight
        function BtnBwdRightValueChanged(app, event)
            value = app.BtnBwdRight.Value;
             if value == 1  % Button is pressed
                app.baseControl.move(app.connection.sim, app.connection.clientID, app.baseControl.WheelMovements.DIAG_BR,app.wheelsSpeed);
            else
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
            end
        end

        % Button pushed function: BtnCamera
        function BtnCameraButtonPushed(app, event)
            app.visionSensor.captureAndDisplayImage(app.connection.sim, app.connection.clientID, app);
        end

        % Button pushed function: BtnStream
        function BtnStreamButtonPushed(app, event)
            % Toggle streaming state
            app.visionSensor.isStreaming = ~app.visionSensor.isStreaming;

            if app.visionSensor.isStreaming
                app.BtnStream.Text = 'Stop';
                % Start streaming
                while app.visionSensor.isStreaming
                    app.visionSensor.captureAndDisplayImage(app.connection.sim, app.connection.clientID, app);
                    pause(0.1);  % Control the frame rate of the stream
                end
            else
                app.BtnStream.Text = 'Start';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sensor')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @BtnConnectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.BtnSimulate = uibutton(app.UIFigure, 'push');
            app.BtnSimulate.ButtonPushedFcn = createCallbackFcn(app, @BtnSimulateButtonPushed, true);
            app.BtnSimulate.Icon = fullfile(pathToMLAPP, 'icons', 'Start.png');
            app.BtnSimulate.Enable = 'off';
            app.BtnSimulate.Text = '';
            app.SPEEDLabel = uilabel(app.UIFigure);
            app.SPEEDLabel.Enable = 'off';
            app.SPEEDLabel.Text = 'SPEED';
            app.SldSpeed = uislider(app.UIFigure);
            app.SldSpeed.ValueChangedFcn = createCallbackFcn(app, @SldSpeedValueChanged, true);
            app.SldSpeed.Enable = 'off';
            app.BtnForward = uibutton(app.UIFigure, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @BtnForwardValueChanged, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.UIFigure, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @BtnRightValueChanged, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.UIFigure, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @BtnBackwardValueChanged, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.UIFigure, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @BtnLeftValueChanged, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFwdRight = uibutton(app.UIFigure, 'state');
            app.BtnFwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnFwdRightValueChanged, true);
            app.BtnFwdRight.Enable = 'off';
            app.BtnFwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'FwdRight.png');
            app.BtnFwdRight.Text = '';
            app.BtnBwdRight = uibutton(app.UIFigure, 'state');
            app.BtnBwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnBwdRightValueChanged, true);
            app.BtnBwdRight.Enable = 'off';
            app.BtnBwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'BwdRight.png');
            app.BtnBwdRight.Text = '';
            app.BtnFwdLeft = uibutton(app.UIFigure, 'state');
            app.BtnFwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnFwdLeftValueChanged, true);
            app.BtnFwdLeft.Enable = 'off';
            app.BtnFwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'FwdLeft.png');
            app.BtnFwdLeft.Text = '';
            app.BtnBwdLeft = uibutton(app.UIFigure, 'state');
            app.BtnBwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnBwdLeftValueChanged, true);
            app.BtnBwdLeft.Enable = 'off';
            app.BtnBwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'BwdLeft.png');
            app.BtnBwdLeft.Text = '';
            app.BtnStream = uibutton(app.UIFigure, 'push');
            app.BtnStream.ButtonPushedFcn = createCallbackFcn(app, @BtnStreamButtonPushed, true);
            app.BtnStream.Icon = fullfile(pathToMLAPP, 'icons', 'streaming.png');
            app.BtnStream.Enable = 'off';
            app.BtnStream.Text = '';
            app.BtnCamera = uibutton(app.UIFigure, 'push');
            app.BtnCamera.ButtonPushedFcn = createCallbackFcn(app, @BtnCameraButtonPushed, true);
            app.BtnCamera.Icon = fullfile(pathToMLAPP, 'icons', 'camera.png');
            app.BtnCamera.Enable = 'off';
            app.BtnCamera.Text = '';
            app.BtnCCW = uibutton(app.UIFigure, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @BtnCCWValueChanged, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.UIFigure, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @BtnCWValueChanged, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end